classdef Matlab_Database_Importer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        OperationsButtonGroup         matlab.ui.container.ButtonGroup
        DenoiceButton                 matlab.ui.control.RadioButton
        ScalogramButton               matlab.ui.control.RadioButton
        SpectrogramButton             matlab.ui.control.RadioButton
        PersistenceSpectrumButton     matlab.ui.control.RadioButton
        SpectrumButton                matlab.ui.control.RadioButton
        DerivativeButton              matlab.ui.control.RadioButton
        OrginalPanel                  matlab.ui.container.Panel
        ToDatabaseButton              matlab.ui.control.Button
        ImportButton                  matlab.ui.control.Button
        MesswertDropDown              matlab.ui.control.DropDown
        MesswertDropDownLabel         matlab.ui.control.Label
        EndEditField                  matlab.ui.control.EditField
        EndEditFieldLabel             matlab.ui.control.Label
        BeginEditField                matlab.ui.control.EditField
        BeginEditFieldLabel           matlab.ui.control.Label
        TabelleDropDown               matlab.ui.control.DropDown
        TabelleDropDownLabel          matlab.ui.control.Label
        ConnectButton                 matlab.ui.control.Button
        PasswordEditField             matlab.ui.control.EditField
        PasswordEditFieldLabel        matlab.ui.control.Label
        UsernameEditField             matlab.ui.control.EditField
        UsernameEditFieldLabel        matlab.ui.control.Label
        DatabaseNameEditField         matlab.ui.control.EditField
        DatabaseNameEditFieldLabel    matlab.ui.control.Label
        PortEditField                 matlab.ui.control.EditField
        PortEditFieldLabel            matlab.ui.control.Label
        DatabaseAdressEditField       matlab.ui.control.EditField
        DatabaseAdressEditFieldLabel  matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        conn;
        connSave;
        sqliteDB;
        column;
        row;
        index;
        timestampFirst;
        timestampFirstString;
        timestampLast;
        timestampLastString;
        calculatedData;
        databaseName;
        loadedTable;
        data;
        result;
        dataSetName;
    end
    
    methods (Access = private)
        
        function results = fCalculate(app,x,y)  %Die Funktion führt die durch den RadioButton ausgewählte Berechnungsfunktion aus.
            if app.DerivativeButton.Value==true
                fAbleitung(app,x,y);
                app.UIAxes2.Title.String=app.DerivativeButton.Text
            elseif app.SpectrumButton.Value==true  
                fSpectrum(app,y);
                app.UIAxes2.Title.String=app.SpectrumButton.Text
            elseif app.PersistenceSpectrumButton.Value==true  
                fPersistenceSpectrum(app,y)
                app.UIAxes2.Title.String=app.PersistenceSpectrumButton.Text
            elseif app.SpectrogramButton.Value==true
                fSpectrogram(app,y)
                app.UIAxes2.Title.String=app.SpectrogramButton.Text
            elseif app.ScalogramButton.Value==true  
                fScalogram(app,y)
                app.UIAxes2.Title.String=app.ScalogramButton.Text
            elseif app.DenoiceButton.Value==true  
                fDenoice(app,y)
                app.UIAxes2.Title.String=app.DenoiceButton.Text
                
            end
        
            assignin('base', strrep(strrep(strrep(append('StdAD64_',app.dataSetName,'_',app.MesswertDropDown.Items{app.MesswertDropDown.Value}),', ','_'),'.',''),':',''),std(y));
            assignin('base', strrep(strrep(strrep(append(app.dataSetName,'_',app.MesswertDropDown.Items{app.MesswertDropDown.Value},'_',app.BeginEditField.Value),', ','_'),'.',''),':',''), y);
        end
        
        function results = fAbleitung(app,x,y)      %Berechnet die Ableitung
            app.result = gradient(y) ./ gradient(x)
            app.UIAxes2.YScale='linear';
            plot(app.UIAxes2,x,app.result)
            %assignin('base', 'ableitung', app.result);
        end
        
        function results = fSpectrum(app,y)         %Erstellt ein Spectrum
            % Parameters
            frequencyLimits = [0 1]*pi; % Normalized frequency (rad/sample)

            %%
            % Index into signal time region of interest
            timeLimits = [1 size(y)]; % samples
            y_ROI = y(:);
            y_ROI = y_ROI(timeLimits(1):timeLimits(2));

            % Compute spectral estimate
            % Run the function call below without output arguments to plot the results
            [Py_ROI, Fy_ROI] = pspectrum(y_ROI, ...
            'FrequencyLimits',frequencyLimits);
            app.UIAxes2.YScale='log';
            plot(app.UIAxes2,Fy_ROI,Py_ROI)
        end
        
        
        function results = fPersistenceSpectrum(app,y)      %erstellt ein persistentes Spektrum
           % Parameters
            frequencyLimits = [0 1]*pi; % Normalized frequency (rad/sample)
            overlapPercent = 50;

            %%
            % Index into signal time region of interest
            timeLimits = [1 size(y)]; % samples
            y_ROI = y(:);
            y_ROI = y_ROI(timeLimits(1):timeLimits(2));

            % Compute spectral estimate
            % Run the function call below without output arguments to plot the results
            [P,F,T] = pspectrum(y_ROI, ...
            'spectrogram', ...
            'FrequencyLimits',frequencyLimits, ...
            'OverlapPercent',overlapPercent);
            app.UIAxes2.YScale='log';
            plot(app.UIAxes2,F,P)

        end
        function result = fSpectrogram(app,y)       %erstellt ein Spektrogram
            % Parameters
            frequencyLimits = [0 1]*pi; % Normalized frequency (rad/sample)
            overlapPercent = 50;

            %%
            % Index into signal time region of interest
            timeLimits = [1 size(y)]; % samples
            y_ROI = y(:);
            y_ROI = y_ROI(timeLimits(1):timeLimits(2));

            % Compute spectral estimate
            % Run the function call below without output arguments to plot the results
            [P,F,T] = pspectrum(y_ROI, ...
            'spectrogram', ...
            'FrequencyLimits',frequencyLimits, ...
            'OverlapPercent',overlapPercent);
            assignin('base', 'spectrogramDataP', P);
            assignin('base', 'spectrogramDataF', F);
            assignin('base', 'spectrogramDataT', T);
            app.UIAxes2.YScale='log';
            imagesc(app.UIAxes2,T,F,P);
            colormap(app.UIAxes2,'jet')
            set(app.UIAxes2,'YDir','normal') 
            %surf(app.UIAxes2,T,F,P)
             %app.UIAxes2.YScale='log';
             %plot(app.UIAxes2,F,P)
        end
        function result = fScalogram(app,y)
            % Parameters
            frequencyLimits = [0 1]; % Normalized frequency (*pi rad/sample)
            voicesPerOctave = 8;
            
            %%
            % Index into signal time region of interest
            timeLimits = [1 size(y)]; % samples
            y_ROI = y(:);
            y_ROI = y_ROI(timeLimits(1):timeLimits(2));
            
            
            %%
            % Convert to cycles/samples
            frequencyLimits =frequencyLimits/2;
            
            %%
            % Limit the cwt frequency limits
            frequencyLimits(1) = max(frequencyLimits(1),...
                cwtfreqbounds(numel(y_ROI)));
            
            
            % Compute cwt
            % Run the function call below without output arguments to plot the results
            app.UIAxes.HandleVisibility='callback';
            cwt(y_ROI, ...
                'VoicesPerOctave',voicesPerOctave, ...
                'FrequencyLimits',frequencyLimits);
            app.UIAxes.HandleVisibility='off';
            %plot(app.UIAxes2,WT,F)

        end
        function result = fDenoice(app,y)       %Hohe Frequenzen werden verringert
            % FUNC_DENOISE_DW1D Saved Denoising Process.
            %   SIG: vector of data
            %   -------------------
            %   sigDEN: vector of denoised data
            % Analysis parameters.
            %---------------------
            wname = 'haar';
            level = 5;
            
            % Denoising parameters.
            %----------------------
            % meth = 'sqtwolog';
            % scal_or_alfa = one;
            sorh = 's';    % Specified soft or hard thresholding
            thrSettings =  [...
                1.769944841852424 ; ...
                1.126311000000000 ; ...
                1.295719436408978 ; ...
                0.976940750000000 ; ...
                1.535499699462728   ...
                ];

            result = cmddenoise(y,wname,level,sorh,NaN,thrSettings);
            app.UIAxes2.YScale='linear';
            plot(app.UIAxes2,result)
            end
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.ConnectButton.BackgroundColor='y';                  % Button einfärben, damit der User erkennt, was der nächste Schritt ist
            
            
            connectorFile = 'mysql-connector-java-8.0.21.jar';
            absPath = fileparts(mfilename('fullpath'));             % absoluter Pfad zum Projektordner
            connectorAbsolute = fullfile(absPath, connectorFile);   % absoluter Pfad zur Datenbank-Connector-Datei
            javaaddpath(connectorAbsolute)                          % Datenbank-Connector einbinden
            
            %Verbindungsparameter von Textfeldern in Variablen speichern
            databaseAdress = get(app.DatabaseAdressEditField, 'Value');
            databasePort = get(app.PortEditField, 'Value');
            app.databaseName = get(app.DatabaseNameEditField, 'Value');
            userName = get(app.UsernameEditField, 'Value');
            password = get(app.PasswordEditField, 'Value');
            
            % Verbindung mit Datenbank herstellen
            app.conn = database(app.databaseName,userName,password,'com.mysql.jdbc.Driver',append('jdbc:mysql://',databaseAdress,':',databasePort,'/',app.databaseName));
            %app.conn = database('test','root',password,'com.mysql.jdbc.Driver',append('jdbc:mysql://','localhost',':','3306','/','test'));
            %assignin('base', 'Tables', sqlfind(app.conn,'').Table);
            
            % Alle Datenbanken, die den Namen ultrasonic enthalten, finden
            % und in das DropdownMenue füllen
            app.TabelleDropDown.Items= sqlfind(app.conn,'ultrasonic').Table
            app.TabelleDropDown.ItemsData=sqlfind(app.conn,'ultrasonic').Table;
           
            
            % Befehl zum automatischen Beziehen der Grafanadatenbank über
            % ssh
            command = 'pscp -P 22 -pw ".,eah19" visualisation@194.94.38.78:/home/visualisation/grafana.db D:\ableitungscript\grafana.db';
            %[status, cmdout] = system(command);
            
            % Oeffnen der Grafanadatenbank
            sqliteName='grafana.db';
            absPath = fileparts(mfilename('fullpath'));
            sqliteAbsolute = fullfile(absPath, sqliteName);
            dbfile = fullfile(sqliteAbsolute);
            app.sqliteDB=sqlite(dbfile,"readonly");
            
            % Auslesen der Annotationen in der Grafanadatenbank
            annotation_data = fetch(app.sqliteDB,'SELECT text, epoch, epoch_end FROM annotation WHERE alert_id=0 AND epoch<>epoch_end ORDER BY id DESC ')
            annotation_data=cell2table(annotation_data,'VariableNames',{'Name' 'Begin' 'End'})
            annotation_data.Begin =  datetime( annotation_data.Begin/1000, 'ConvertFrom', 'posixtime','Format','dd.MM.yyyy, HH:mm:ss' );
            annotation_data.End =  datetime( annotation_data.End/1000, 'ConvertFrom', 'posixtime','Format','dd.MM.yyyy, HH:mm:ss' );
            
            % Annotationen in Tabelle anzeigen
            app.UITable.Data = annotation_data
            app.UITable.ColumnName = annotation_data.Properties.VariableNames;
            
            app.ConnectButton.BackgroundColor='g';                  % Button einfärben, damit der User erkennt, was der nächste Schritt ist
            app.TabelleDropDown.BackgroundColor='r';                % Button einfärben, damit der User erkennt, was der nächste Schritt ist
            
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            app.ImportButton.BackgroundColor='y';                   % Button einfärben, damit der User erkennt, was der nächste Schritt ist
            % Auslesen und Umformen der Anfangs und Endzeiten der Daten
            beginTime=datetime(get(app.BeginEditField,'Value'),"InputFormat","dd.MM.yyyy, HH:mm:ss")
            beginTimeFormated=datetime(beginTime,"Format","yyyy-MM-dd HH:mm:ss")
            endTime=datetime(get(app.EndEditField,'Value'),"InputFormat","dd.MM.yyyy, HH:mm:ss")
            endTimeFormated=datetime(endTime,"Format","yyyy-MM-dd HH:mm:ss")
           
            % Ausgewählte Daten von der Datenbank abrufen
             query = append('SELECT `id` , `',app.MesswertDropDown.Items{app.MesswertDropDown.Value},'` , `timestamp` FROM ',app.databaseName,'.',app.loadedTable,' WHERE timestamp >= "',string(beginTimeFormated),'" ',' AND timestamp < "',string(endTimeFormated),'"'); 
             assignin('base', 'query', query);
             app.data = fetch(app.conn,query);
            dataID=table2array(app.data(:,1));
            assignin('base', 'dataID', dataID);
            
            % Rohdaten in den ersten Graphen zeichnen
            plot(app.UIAxes,dataID,table2array(app.data(:,2)))
            
            % Ausgewählte Berechnung durchführen. In der
            % Berechnungsfunktion ist auch das Zeichnen des Ergebnisses
            % enthalten.
            fCalculate(app,dataID,table2array(app.data(:,2)))


            %assignin('base', 'rawData', table(dataID,table2array(data(:,2))));
            app.ImportButton.BackgroundColor='g';                   % Button einfärben, damit der User erkennt, was der nächste Schritt ist
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            
            app.column = indices(2);
            app.row = indices(1); 
            
            % Zeiten der ausgewählten Annotation in die Textfelder
            % übernehmen
            tableData= get(app.UITable,'data')
            begintime=datestr(tableData.Begin(app.row),'dd.mm.yyyy, HH:MM:ss')
            endtime=datestr(tableData.End(app.row),'dd.mm.yyyy, HH:MM:ss')
            app.BeginEditField.Value=begintime;
            app.EndEditField.Value=endtime;
            app.dataSetName=string(strrep(strrep(tableData.Name(app.row),' ','_'),newline,'_'));

        end

        % Button pushed function: ToDatabaseButton
        function ToDatabaseButtonPushed(app, event)
            
            % Daten an die Struktur der Datenbank anpassen
            tablename = 'calculated';
            columnNames=["timestamp","value","name","tableName","tableID"];
            tableNameTable(1:size(app.data(:,3))) = {app.loadedTable};
            valueNameTable(1:size(app.data(:,3))) = {app.MesswertDropDown.Items{app.MesswertDropDown.Value}};
            exportTable=table(table2array(app.data(:,3)),app.result,valueNameTable.',tableNameTable.',table2array(app.data(:,1)));
            exportTable.Properties.VariableNames=columnNames;
            assignin('base', "exportTable", exportTable);
            
            % Verbindung zur Datenbank für Berechnungsergebnisse herstellen
            databaseAdress = get(app.DatabaseAdressEditField, 'Value');
            databasePort = get(app.PortEditField, 'Value');
            userName = get(app.UsernameEditField, 'Value');
            password = get(app.PasswordEditField, 'Value');
            app.connSave = database('wolf',userName,password,'com.mysql.jdbc.Driver',append('jdbc:mysql://',databaseAdress,':',databasePort,'/','wolf'));
            
            % Daten in die Datenbank schreiben
            sqlwrite(app.connSave,tablename,exportTable)
            close(app.connSave)
            f = msgbox('Daten erfolgreich zur Datenbank übertragen.')
        end

        % Value changed function: TabelleDropDown
        function TabelleDropDownValueChanged(app, event)
            app.TabelleDropDown.BackgroundColor='y';                % Button einfärben, damit der User erkennt, was der nächste Schritt ist
            
            % Auswahl, welcher Wert gelesen werden soll
            
            % Lade einen Datensatz der ausgewählten Datenbank um die
            % Datenbankfeldernamen zu erhalten
            app.loadedTable = app.TabelleDropDown.Value;
            query = append('SELECT * FROM ',app.databaseName,'.',app.loadedTable,' LIMIT 1'); 
            data = fetch(app.conn,query);
            
            % Datenbankfeldernamen ins Dropdownmenue eintragen
            app.MesswertDropDown.Items = data.Properties.VariableNames;
            app.MesswertDropDown.ItemsData=1:length(data.Properties.VariableNames);

            
            app.TabelleDropDown.BackgroundColor='g';                % Button einfärben, damit der User erkennt, was der nächste Schritt ist
            app.MesswertDropDown.BackgroundColor='r';               % Button einfärben, damit der User erkennt, was der nächste Schritt ist
            
        end

        % Selection changed function: OperationsButtonGroup
        function OperationsButtonGroupSelectionChanged(app, event)
            % Führe beim Wechsel der Berechnungsfunktion diese direkt
            % einmal aus
            dataID=table2array(app.data(:,1));
            fCalculate(app,dataID,table2array(app.data(:,2)));
            
        end

        % Value changed function: MesswertDropDown
        function MesswertDropDownValueChanged(app, event)
            app.MesswertDropDown.BackgroundColor='g';               % Button einfärben, damit der User erkennt, was der nächste Schritt ist
            app.ImportButton.BackgroundColor='r';                   % Button einfärben, damit der User erkennt, was der nächste Schritt ist
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            close(app.conn)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Orginal')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Derivative')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {''};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.OrginalPanel = uipanel(app.UIFigure);
            app.OrginalPanel.Title = 'Orginal';
            app.DatabaseAdressEditFieldLabel = uilabel(app.OrginalPanel);
            app.DatabaseAdressEditFieldLabel.Text = 'Database Adress';
            app.DatabaseAdressEditField = uieditfield(app.OrginalPanel, 'text');
            app.DatabaseAdressEditField.Value = 'dbserv.fc.eah-jena.de';
            app.PortEditFieldLabel = uilabel(app.OrginalPanel);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.OrginalPanel, 'text');
            app.PortEditField.Value = '3306';
            app.DatabaseNameEditFieldLabel = uilabel(app.OrginalPanel);
            app.DatabaseNameEditFieldLabel.Text = 'Database Name';
            app.DatabaseNameEditField = uieditfield(app.OrginalPanel, 'text');
            app.DatabaseNameEditField.Value = 'machines';
            app.UsernameEditFieldLabel = uilabel(app.OrginalPanel);
            app.UsernameEditFieldLabel.Text = 'Username';
            app.UsernameEditField = uieditfield(app.OrginalPanel, 'text');
            app.UsernameEditField.Value = 'wolf';
            app.PasswordEditFieldLabel = uilabel(app.OrginalPanel);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.OrginalPanel, 'text');
            app.PasswordEditField.Value = '.,Wolf12345!!!';
            app.ConnectButton = uibutton(app.OrginalPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.TabelleDropDownLabel = uilabel(app.OrginalPanel);
            app.TabelleDropDownLabel.Text = 'Tabelle';
            app.TabelleDropDown = uidropdown(app.OrginalPanel);
            app.TabelleDropDown.Items = {};
            app.TabelleDropDown.ValueChangedFcn = createCallbackFcn(app, @TabelleDropDownValueChanged, true);
            app.TabelleDropDown.Value = {};
            app.BeginEditFieldLabel = uilabel(app.OrginalPanel);
            app.BeginEditFieldLabel.Text = 'Begin';
            app.BeginEditField = uieditfield(app.OrginalPanel, 'text');
            app.EndEditFieldLabel = uilabel(app.OrginalPanel);
            app.EndEditFieldLabel.Text = 'End';
            app.EndEditField = uieditfield(app.OrginalPanel, 'text');
            app.MesswertDropDownLabel = uilabel(app.OrginalPanel);
            app.MesswertDropDownLabel.Text = 'Messwert';
            app.MesswertDropDown = uidropdown(app.OrginalPanel);
            app.MesswertDropDown.Items = {};
            app.MesswertDropDown.ValueChangedFcn = createCallbackFcn(app, @MesswertDropDownValueChanged, true);
            app.MesswertDropDown.Value = {};
            app.ImportButton = uibutton(app.OrginalPanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.ToDatabaseButton = uibutton(app.OrginalPanel, 'push');
            app.ToDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @ToDatabaseButtonPushed, true);
            app.ToDatabaseButton.Text = 'To Database';
            app.OperationsButtonGroup = uibuttongroup(app.UIFigure);
            app.OperationsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OperationsButtonGroupSelectionChanged, true);
            app.OperationsButtonGroup.Title = 'Operations:';
            app.DerivativeButton = uiradiobutton(app.OperationsButtonGroup);
            app.DerivativeButton.Text = 'Derivative';
            app.DerivativeButton.Value = true;
            app.SpectrumButton = uiradiobutton(app.OperationsButtonGroup);
            app.SpectrumButton.Text = 'Spectrum';
            app.PersistenceSpectrumButton = uiradiobutton(app.OperationsButtonGroup);
            app.PersistenceSpectrumButton.Text = 'Persistence Spectrum';
            app.SpectrogramButton = uiradiobutton(app.OperationsButtonGroup);
            app.SpectrogramButton.Text = 'Spectrogram';
            app.ScalogramButton = uiradiobutton(app.OperationsButtonGroup);
            app.ScalogramButton.Text = 'Scalogram';
            app.DenoiceButton = uiradiobutton(app.OperationsButtonGroup);
            app.DenoiceButton.Text = 'Denoice';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Matlab_Database_Importer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end