classdef Matlab_Continually < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        OneStepButton                   matlab.ui.control.Button
        OverlapEditField                matlab.ui.control.EditField
        OverlapEditFieldLabel           matlab.ui.control.Label
        MaximumLengthEditField          matlab.ui.control.EditField
        MaximumLengthEditFieldLabel     matlab.ui.control.Label
        IntervalEditField               matlab.ui.control.EditField
        IntervalEditFieldLabel          matlab.ui.control.Label
        StopButton                      matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        MinimumLengthEditField          matlab.ui.control.EditField
        MinimumLengthEditFieldLabel     matlab.ui.control.Label
        PasswordEditField               matlab.ui.control.EditField
        PasswordEditField_2Label        matlab.ui.control.Label
        UsernameEditField               matlab.ui.control.EditField
        UsernameEditField_2Label        matlab.ui.control.Label
        DatabaseNameEditField           matlab.ui.control.EditField
        DatabaseNameEditField_2Label    matlab.ui.control.Label
        PortEditField                   matlab.ui.control.EditField
        PortEditField_2Label            matlab.ui.control.Label
        DatabaseAdressEditField         matlab.ui.control.EditField
        DatabaseAdressEditField_2Label  matlab.ui.control.Label
        ConnectButton                   matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        t;
        lastTimeStamp;
        lastID;
        conn;
        data;
        result;
        connSave;
    end


    methods (Access = private)
        % Timerfunktion wird wiederholend ausgeführt
        function  timerFunction(app)
            % Textfelder auslesen und in Variablen speichern
            minimumDatasetLength = str2num(get(app.MinimumLengthEditField, 'Value'));
            maximumDatasetLength = str2num(get(app.MaximumLengthEditField, 'Value'));
            overlap = str2num(get(app.OverlapEditField, 'Value'));
            disp('timerFunction');
            
            % Berechnung der IDs zum Abrufen der Datensätze
             app.lastID=app.lastID-overlap;
             if app.lastID<0
                 overlap=overlap+app.lastID;
                 app.lastID=0;
             end
             
             % Daten aus der Datenbank Abrufen
             query = append('SELECT id , timestamp, vaLoadX, vaLoadY, vaLoadZ FROM ultrasonic3 WHERE id > ',string(app.lastID),' AND id <= ',string(app.lastID+maximumDatasetLength)); 
             app.data = fetch(app.conn,query);
             
             % Bearbeitung der Daten nur, wenn genügend Datensätze vorhanden
             % sind
            if height(app.data)>=minimumDatasetLength
                 
             % table vLoadX 
             % Berechnung durchführen und auf UI anzeigen
            fAbleitung(app,app.data.id,app.data.vaLoadX);
            plot(app.UIAxes,app.data.id,app.result);
            tablename = 'calculated';
            columnNames=["timestamp","value","name","tableName","tableID"];
            tableNameTable(1:size(app.data(:,1))) = "ultrasonic3";
            valueNameTable(1:size(app.data(:,1))) = "vaLoadX";
            tableVaLoadX=table(table2array(app.data(:,2)),app.result,valueNameTable.',tableNameTable.',table2array(app.data(:,1)));
            tableVaLoadX.Properties.VariableNames=columnNames;
            % Da die Datensätze sich überlappen und nicht doppelt gespeichert werden,
            % werden die überlappenden Daten wieder entfernt
            tableVaLoadX(1:overlap,:)=[]                            
                                                                                                                         
            
             % table vLoadY
            fAbleitung(app,app.data.id,app.data.vaLoadY);
            
            tableNameTable(1:size(app.data(:,1))) = "ultrasonic3";
            valueNameTable(1:size(app.data(:,1))) = "vaLoadY";
            tableVaLoadY=table(table2array(app.data(:,2)),app.result,valueNameTable.',tableNameTable.',table2array(app.data(:,1)));
            tableVaLoadY.Properties.VariableNames=columnNames;
            tableVaLoadY(1:overlap,:)=[]
            
            % table vLoadZ
            fAbleitung(app,app.data.id,app.data.vaLoadZ);

            tableNameTable(1:size(app.data(:,1))) = "ultrasonic3";
            valueNameTable(1:size(app.data(:,1))) = "vaLoadZ";
            tableVaLoadZ=table(table2array(app.data(:,2)),app.result,valueNameTable.',tableNameTable.',table2array(app.data(:,1)));
            tableVaLoadZ.Properties.VariableNames=columnNames;
            tableVaLoadZ(1:overlap,:)=[]
            exportTable=[tableVaLoadX;tableVaLoadY;tableVaLoadZ];
            
            
            % Datenbankverbindung zur Speicherung aufbauen
            databaseAdress = get(app.DatabaseAdressEditField, 'Value');
            databasePort = get(app.PortEditField, 'Value');
            userName = get(app.UsernameEditField, 'Value');
            password = get(app.PasswordEditField, 'Value');
            app.connSave = database('test',userName,password,'com.mysql.jdbc.Driver',append('jdbc:mysql://',databaseAdress,':',databasePort,'/','test'));
            
            % Speichere Daten
            sqlwrite(app.connSave,tablename,exportTable);
            
            % Beziehe die letzte gespeicherte ID von der Datenbank
            query = append('SELECT max(tableID) FROM calculated WHERE tableName = "ultrasonic3"'); 
            app.lastID = table2array(fetch(app.connSave,query));
            assignin('base', "newLastId", app.lastID);
            
            close(app.connSave);
            end
        end
        
        function fAbleitung(app,x,y)
            app.result = gradient(y) ./ gradient(x);
            
            %assignin('base', 'ableitung', app.result);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.ConnectButton.BackgroundColor='y';
            %% Make connection to database
            connectorFile = 'mysql-connector-java-8.0.21.jar';
            absPath = fileparts(mfilename('fullpath'));             % absoluter Pfad des Projekts
            connectorAbsolute = fullfile(absPath, connectorFile);   % absoluter Pfad der Connector-Datei
            javaaddpath(connectorAbsolute)                          % Connector einbinden   
            
            %Verbindung zur Datenbank herstellen
            databaseAdress = get(app.DatabaseAdressEditField, 'Value');
            databasePort = get(app.PortEditField, 'Value');
            databaseName = get(app.DatabaseNameEditField, 'Value');
            userName = get(app.UsernameEditField, 'Value');
            password = get(app.PasswordEditField, 'Value');
            
            app.conn = database(databaseName,userName,password,'com.mysql.jdbc.Driver',append('jdbc:mysql://',databaseAdress,':',databasePort,'/',databaseName));
            %app.conn = database('test','root',password,'com.mysql.jdbc.Driver',append('jdbc:mysql://','localhost',':','3306','/','test'));
            %assignin('base', 'Tables', sqlfind(app.conn,'').Table);
            
%              app.connSave = database('wolf',userName,password,'com.mysql.jdbc.Driver',append('jdbc:mysql://',databaseAdress,':',databasePort,'/','wolf'));
             app.connSave = database('test',userName,password,'com.mysql.jdbc.Driver',append('jdbc:mysql://',databaseAdress,':',databasePort,'/','test'));
             query = append('SELECT max(tableID) FROM calculated WHERE tableName = "ultrasonic3"');  
             app.lastID = table2array(fetch(app.connSave,query));
             app.lastID(isnan(app.lastID))=0;
             %app.lastID=0;
             
             close(app.connSave);
             assignin('base', 'lastID', app.lastID);
           
            app.ConnectButton.BackgroundColor='g';
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Beginn der Verarbeitungsschleife
            intervall=get(app.IntervalEditField, 'Value');
            app.t=timer('ExecutionMode', 'fixedSpacing', 'StartDelay',0, 'Period',str2double(intervall));
            app.t.TimerFcn = @(~,~)timerFunction(app);
            %app.t.TimerFcn = @(~,~)disp('do something');
            start(app.t);
            assignin('base', 'timer', app.t);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % Bearbeitungsschleife stoppen
            stop(app.t);
            delete(app.t);
        end

        % Button pushed function: OneStepButton
        function OneStepButtonPushed(app, event)
            timerFunction(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {849, 849};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {381, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ConnectButton = uibutton(app.LeftPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DatabaseAdressEditField_2Label = uilabel(app.LeftPanel);
            app.DatabaseAdressEditField_2Label.Text = 'Database Adress';
            app.DatabaseAdressEditField = uieditfield(app.LeftPanel, 'text');
            app.DatabaseAdressEditField.Value = 'localhost';
            app.PortEditField_2Label = uilabel(app.LeftPanel);
            app.PortEditField_2Label.Text = 'Port';
            app.PortEditField = uieditfield(app.LeftPanel, 'text');
            app.PortEditField.Value = '3306';
            app.DatabaseNameEditField_2Label = uilabel(app.LeftPanel);
            app.DatabaseNameEditField_2Label.Text = 'Database Name';
            app.DatabaseNameEditField = uieditfield(app.LeftPanel, 'text');
            app.DatabaseNameEditField.Value = 'test';
            app.UsernameEditField_2Label = uilabel(app.LeftPanel);
            app.UsernameEditField_2Label.Text = 'Username';
            app.UsernameEditField = uieditfield(app.LeftPanel, 'text');
            app.UsernameEditField.Value = 'root';
            app.PasswordEditField_2Label = uilabel(app.LeftPanel);
            app.PasswordEditField_2Label.Text = 'Password';
            app.PasswordEditField = uieditfield(app.LeftPanel, 'text');
            app.MinimumLengthEditFieldLabel = uilabel(app.LeftPanel);
            app.MinimumLengthEditFieldLabel.Text = 'Minimum Length';
            app.MinimumLengthEditField = uieditfield(app.LeftPanel, 'text');
            app.MinimumLengthEditField.Value = '10';
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.LeftPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.IntervalEditFieldLabel = uilabel(app.LeftPanel);
            app.IntervalEditFieldLabel.Text = 'Interval';
            app.IntervalEditField = uieditfield(app.LeftPanel, 'text');
            app.IntervalEditField.Value = '0.1';
            app.MaximumLengthEditFieldLabel = uilabel(app.LeftPanel);
            app.MaximumLengthEditFieldLabel.Text = 'Maximum Length';
            app.MaximumLengthEditField = uieditfield(app.LeftPanel, 'text');
            app.MaximumLengthEditField.Value = '100';
            app.OverlapEditFieldLabel = uilabel(app.LeftPanel);
            app.OverlapEditFieldLabel.Text = 'Overlap';
            app.OverlapEditField = uieditfield(app.LeftPanel, 'text');
            app.OverlapEditField.Value = '99';
            app.OneStepButton = uibutton(app.LeftPanel, 'push');
            app.OneStepButton.ButtonPushedFcn = createCallbackFcn(app, @OneStepButtonPushed, true);
            app.OneStepButton.Text = 'One Step';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Matlab_Continually

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end